classdef StressLimPlot2016b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        FatigueLimZN       matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        FatigueLimZ        matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        YieldLim           matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        IntensityLim       matlab.ui.control.NumericEditField
        Panel_2            matlab.ui.container.Panel
        maxEditFieldLabel  matlab.ui.control.Label
        maxEditField       matlab.ui.control.NumericEditField
        minEditFieldLabel  matlab.ui.control.Label
        minEditField       matlab.ui.control.NumericEditField
        TextArea           matlab.ui.control.TextArea
        Panel_3            matlab.ui.container.Panel
        KDSliderLabel      matlab.ui.control.Label
        KDSlider           matlab.ui.control.Slider
        kEditFieldLabel    matlab.ui.control.Label
        kEditField         matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        EditField_4        matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        EditField_5        matlab.ui.control.NumericEditField
        KDEditFieldLabel   matlab.ui.control.Label
        KDEditField        matlab.ui.control.NumericEditField
        Panel_4            matlab.ui.container.Panel
        Label_7            matlab.ui.control.Label
        TextArea_2         matlab.ui.control.TextArea
        UIAxes2            matlab.ui.control.UIAxes
        ButtonGroup        matlab.ui.container.ButtonGroup
        fragility          matlab.ui.control.ToggleButton
        ductility          matlab.ui.control.ToggleButton
        Button_2           matlab.ui.control.Button
        Tab_2              matlab.ui.container.Tab
        Label_8            matlab.ui.control.Label
        TextArea_3         matlab.ui.control.TextArea
        Label_6            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button_2, KDSlider
        function Button_2Pushed(app, event)
            %参数计算
            app.KDEditField.Value=app.kEditField.Value/(app.EditField_4.Value*app.EditField_5.Value);
            if  app.KDEditField.Value==1 %默认值
                kd=app.KDSlider.Value;
            else %非默认值
                kd=app.KDEditField.Value;
            end
         
            %参数定义
            
            d_1=app.FatigueLimZN.Value;
            d_0=app.FatigueLimZ.Value;
            d_s=app.YieldLim.Value;
            d_b=app.IntensityLim.Value;
            d_max=app.maxEditField.Value;
            d_min=app.minEditField.Value;
            %绘图
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.fragility %脆性材料
                %绘制直线
                A=[0 d_0/2 d_b];
                B=[d_1/kd d_0/(2*kd) 0];
                AA=[0 d_1/kd+50]; %A点位置
                BB=[d_0/2 d_0/(2*kd)+50];
                C=[d_b,30];
                hold(app.UIAxes,'off');
                plot(app.UIAxes,A,B,'b');
                %text(app.UIAxes,0,d_1/kd );
                text(app.UIAxes,'Interpreter','latex','String',...
                    "${\rm{A}}'\left( {0,\;{{{\sigma _{ - 1}}} \over {{{({K_\sigma })}_D}}}} \right)$",'Position',AA);
                
                text(app.UIAxes,'Interpreter','latex','String',...
                    "$B'\left( {{{{\sigma _0}} \over 2},\;{{{\sigma _0}} \over {2{{({K_\sigma })}_D}}}} \right)$",...
                    'Position',BB);
                text(app.UIAxes,'Interpreter','latex','String',...
                    '$${\rm{C}}(0,{\sigma _b})$$','Position',C);
                k=(d_max-d_min)/(d_max+d_min)  ;  %工作应力点的斜率
                r=d_min/d_max; %循环特征
                dB=1/kd;  %分界点B与原点连接的斜率
                if k>dB  %（m,n）指交点
                    m=(d_0*d_1)/(2*d_1 - d_0 + d_0*k*kd);
                    n=(d_0*d_1*k)/(2*d_1 - d_0 + d_0*k*kd);
                else
                    m=(d_0*d_b)/(d_0 - d_0*k*kd + 2*d_b*k*kd);
                    n=(d_0*d_b*k)/(d_0 - d_0*k*kd + 2*d_b*k*kd);
                end
                hold(app.UIAxes,'on');
                plot(app.UIAxes,(d_max+d_min) ,(d_max-d_min),'*',m,n,'+','Color','k');
                
                plot(app.UIAxes,[0,m],[0,n]);
                if (d_max+d_min)>m
                    str='，处于非安全区。';
                elseif (d_max+d_min)==m
                    str='，处于安全区临界位置。';
                elseif (d_max+d_min)<BB(1)
                    str='，处于疲劳安全区。';
                else
                    str='，处于塑性安全区。';
                end
                d_r=m+n;
                
                STR=sprintf('%s%s%f%s%f%s%s','脆性材料。 ','循环特征  r=',r,' 疲劳极限σr= ',d_r,'Mpa',str);
                app.TextArea_2.Value=STR;
                
            elseif selectedButton==app.ductility %塑性材料
                 %绘制直线
                 s1 = -(d_0*(d_1 - d_s*kd))/(d_0 - 2*d_1 + d_0*kd); %横坐标
                 s2 = (d_0*d_1 + d_0*d_s - 2*d_1*d_s)/(d_0 - 2*d_1 + d_0*kd); %纵坐标
               
                 A=[0 d_0/2 s1 d_s ];
                 B=[d_1/kd d_0/(2*kd) s2 0];
                 AA=[0 d_1/kd+50]; %A点位置 
                 BB=[d_0/2 d_0/(2*kd)+50]; %B点，由于B已经被使用
                C=[d_s,30]; %C点,适当抬高，便于显示
                D=[s1+30,s2]; %D点，适当右移
                 hold(app.UIAxes2,'off');
                 plot(app.UIAxes2,A,B,'b');
                 text(app.UIAxes2,'Interpreter','latex','String',...
                    "${\rm{A}}'\left( {0,\;{{{\sigma _{ - 1}}} \over {{{({K_\sigma })}_D}}}} \right)$",'Position',AA);
                
                text(app.UIAxes2,'Interpreter','latex','String',...
                    "$B'\left( {{{{\sigma _0}} \over 2},\;{{{\sigma _0}} \over {2{{({K_\sigma })}_D}}}} \right)$",...
                    'Position',BB);
                text(app.UIAxes2,'Interpreter','latex','String',...
                    '$${\rm{C}}(0,{\sigma _b})$$','Position',C);
                 text(app.UIAxes2,'Interpreter','latex','String',...
                    "D'",'Position',D );
                 
                 k=(d_max-d_min)/(d_max+d_min) ;  %工作应力点的斜率
                 r=d_min/d_max ;%循环特征
                 dD=s2/s1;  %分界点B与原点连接的斜率
                 if k>dD
                    m=(d_0*d_1)/(2*d_1 - d_0 + d_0*k*kd);
                    n=(d_0*d_1*k)/(2*d_1 - d_0 + d_0*k*kd);
                 else
                     m=d_s/(k + 1);
                     n=(d_s*k)/(k + 1);
                 end
                
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,(d_max+d_min) ,(d_max-d_min),'*',m,n,'+','Color','k');
                plot(app.UIAxes2,[0,m],[0,n]);
                if (d_max+d_min)>m
                    str='，处于非安全区。';
                elseif (d_max+d_min)==m
                    str='，处于安全区临界位置。';
                elseif (d_max+d_min)<s1
                    str='，处于疲劳安全区。';
                else
                    str='，处于塑性安全区。';
                end
                d_r=m+n;
               
                STR=sprintf('%s%s%f%s%f%s%s','塑性材料。 ','循环特征  r=',r,'疲劳极限σr= ',d_r,'Mpa',str);
                app.TextArea_2.Value=STR;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '机械设计';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主界面';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '脆性材料极限应力简图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 500];
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '材料常数';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'σ-1';
            app.FatigueLimZN = uieditfield(app.Panel, 'numeric');
            app.FatigueLimZN.Value = 400;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'σ0';
            app.FatigueLimZ = uieditfield(app.Panel, 'numeric');
            app.FatigueLimZ.Value = 658;
            app.sEditFieldLabel = uilabel(app.Panel);
            app.sEditFieldLabel.Text = 'σs';
            app.YieldLim = uieditfield(app.Panel, 'numeric');
            app.YieldLim.Value = 780;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = ' σb';
            app.IntensityLim = uieditfield(app.Panel, 'numeric');
            app.IntensityLim.Value = 850;
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '工作常数';
            app.maxEditFieldLabel = uilabel(app.Panel_2);
            app.maxEditFieldLabel.Text = 'σmax';
            app.maxEditField = uieditfield(app.Panel_2, 'numeric');
            app.maxEditField.Value = 300;
            app.minEditFieldLabel = uilabel(app.Panel_2);
            app.minEditFieldLabel.Text = 'σmin';
            app.minEditField = uieditfield(app.Panel_2, 'numeric');
            app.minEditField.Value = 100;
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Value = {'* 号表示工作点+号表示极限点'};
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '修正系数';
            app.KDSliderLabel = uilabel(app.Panel_3);
            app.KDSliderLabel.Text = '(Kσ)D';
            app.KDSlider = uislider(app.Panel_3);
            app.KDSlider.Limits = [1 5];
            app.KDSlider.MajorTicks = [1 2 3 4 5];
            app.KDSlider.ValueChangedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.KDSlider.Value = 1.62;
            app.kEditFieldLabel = uilabel(app.Panel_3);
            app.kEditFieldLabel.Text = ' kσ';
            app.kEditField = uieditfield(app.Panel_3, 'numeric');
            app.kEditField.Value = 1;
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = 'εσ';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_4.Value = 1;
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = 'β';
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_5.Value = 1;
            app.KDEditFieldLabel = uilabel(app.Panel_3);
            app.KDEditFieldLabel.Text = '(Kσ)D';
            app.KDEditField = uieditfield(app.Panel_3, 'numeric');
            app.KDEditField.Value = 1;
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '校核';
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = '结论';
            app.TextArea_2 = uitextarea(app.Panel_4);
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, '塑性材料极限应力简图')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [0 500];
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.Title = '材料性质';
            app.fragility = uitogglebutton(app.ButtonGroup);
            app.fragility.Text = '脆性材料';
            app.fragility.Value = true;
            app.ductility = uitogglebutton(app.ButtonGroup);
            app.ductility.Text = '塑性材料';
            app.Button_2 = uibutton(app.ButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '绘制';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '附表';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '程序说明';
            app.TextArea_3 = uitextarea(app.Tab_2);
            app.TextArea_3.Value = {'说明：'; '1.分两个坐标系分别画出脆性材料和塑性材料下的极限应力图'; '2.输入工作常数，可以计算此循环特征下的疲劳极限，同时判断是否处于安全区域。'; '3.修正系数(Kσ)D有两种模式，一种由 kσ、εσ、β 计算得到；一种通过滑块直接给定。'; '4.滑动滑块可以直观看到图形的变化。'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '变应力作用下的强度问题';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StressLimPlot2016b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end